classdef cyclingEfficiency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Rider2Label                     matlab.ui.control.Label
        Rider1Label_2                   matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        PowerEfficiencyTab_2            matlab.ui.container.Tab
        MetabolicPowerWSpinner_2Label   matlab.ui.control.Label
        MetabolicPowerWSpinner_2        matlab.ui.control.Spinner
        GrossEfficiencySpinner_2Label   matlab.ui.control.Label
        GrossEfficiencySpinner_2        matlab.ui.control.Spinner
        MechanicalPowerWSpinner_2Label  matlab.ui.control.Label
        MechanicalPowerWSpinner_2       matlab.ui.control.Spinner
        RiderEquipmentTab_2             matlab.ui.container.Tab
        RidermasskgSpinner_2Label       matlab.ui.control.Label
        RidermasskgSpinner_2            matlab.ui.control.Spinner
        BikemasskgSpinner_2Label        matlab.ui.control.Label
        BikemasskgSpinner_2             matlab.ui.control.Spinner
        CrrSpinnerLabel                 matlab.ui.control.Label
        CrrSpinner                      matlab.ui.control.Spinner
        SpokedragfactorSpinner_3Label   matlab.ui.control.Label
        SpokedragfactorSpinner_3        matlab.ui.control.Spinner
        CdASpinner_2Label               matlab.ui.control.Label
        CdASpinner_2                    matlab.ui.control.Spinner
        EnvironmentTab_2                matlab.ui.container.Tab
        Gravityms2Spinner_2Label        matlab.ui.control.Label
        Gravityms2Spinner_2             matlab.ui.control.Spinner
        GradientSpinner_2Label          matlab.ui.control.Label
        GradientSpinner_2               matlab.ui.control.Spinner
        WinddirectiondegreesSpinner_2Label  matlab.ui.control.Label
        WinddirectiondegreesSpinner_2   matlab.ui.control.Spinner
        RidedirectiondegreesSpinner_2Label  matlab.ui.control.Label
        RidedirectiondegreesSpinner_2   matlab.ui.control.Spinner
        WindvelocitymsSpinner_2Label    matlab.ui.control.Label
        WindvelocitymsSpinner_2         matlab.ui.control.Spinner
        Airdensitykgm3Spinner_2Label    matlab.ui.control.Label
        Airdensitykgm3Spinner_2         matlab.ui.control.Spinner
        ChooseascenarioButtonGroup_2    matlab.ui.container.ButtonGroup
        ConstantMetabolicPowerButton_2  matlab.ui.control.RadioButton
        ConstantGrossEfficiencyButton_2  matlab.ui.control.RadioButton
        ConstantMechanicalPowerButton_2  matlab.ui.control.RadioButton
        TranslatingMetabolicEfficiencytoCyclingPerformanceLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        RestoredefaultButton            matlab.ui.control.Button
        TabGroup_3                      matlab.ui.container.TabGroup
        PowerEfficiencyTab_3            matlab.ui.container.Tab
        MetabolicPowerWSpinner_3Label   matlab.ui.control.Label
        MetabolicPowerWSpinner_3        matlab.ui.control.Spinner
        GrossEfficiencySpinner_3Label   matlab.ui.control.Label
        GrossEfficiencySpinner_3        matlab.ui.control.Spinner
        MechanicalPowerWSpinner_3Label  matlab.ui.control.Label
        MechanicalPowerWSpinner_3       matlab.ui.control.Spinner
        RiderEquipmentTab_3             matlab.ui.container.Tab
        RidermasskgSpinner_3Label       matlab.ui.control.Label
        RidermasskgSpinner_3            matlab.ui.control.Spinner
        BikemasskgSpinner_3Label        matlab.ui.control.Label
        BikemasskgSpinner_3             matlab.ui.control.Spinner
        CoefficientoffrictionSpinner_4Label  matlab.ui.control.Label
        CoefficientoffrictionSpinner_4  matlab.ui.control.Spinner
        SpokedragfactorSpinner_4Label   matlab.ui.control.Label
        SpokedragfactorSpinner_4        matlab.ui.control.Spinner
        CdASpinner_3Label               matlab.ui.control.Label
        CdASpinner_3                    matlab.ui.control.Spinner
        EnvironmentTab_3                matlab.ui.container.Tab
        Gravityms2Spinner_3Label        matlab.ui.control.Label
        Gravityms2Spinner_3             matlab.ui.control.Spinner
        GradientSpinner_3Label          matlab.ui.control.Label
        GradientSpinner_3               matlab.ui.control.Spinner
        WinddirectiondegreesSpinner_3Label  matlab.ui.control.Label
        WinddirectiondegreesSpinner_3   matlab.ui.control.Spinner
        RidedirectiondegreesSpinner_3Label  matlab.ui.control.Label
        RidedirectiondegreesSpinner_3   matlab.ui.control.Spinner
        WindvelocitymsSpinner_3Label    matlab.ui.control.Label
        WindvelocitymsSpinner_3         matlab.ui.control.Spinner
        Airdensitykgm3Spinner_3Label    matlab.ui.control.Label
        Airdensitykgm3Spinner_3         matlab.ui.control.Spinner
        ResultsPanel                    matlab.ui.container.Panel
        VelocitymsEditFieldLabel        matlab.ui.control.Label
        VelocitymsEditField             matlab.ui.control.NumericEditField
        Timetotravel1kmsEditFieldLabel  matlab.ui.control.Label
        Timetotravel1kmsEditField       matlab.ui.control.NumericEditField
        VelocitymsEditField_2Label      matlab.ui.control.Label
        VelocitymsEditField_2           matlab.ui.control.NumericEditField
        Timetotravel1kmsEditField_2Label  matlab.ui.control.Label
        Timetotravel1kmsEditField_2     matlab.ui.control.NumericEditField
        Rider1Label_3                   matlab.ui.control.Label
        Rider2Label_2                   matlab.ui.control.Label
        UndertheHoodPanel               matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        DropDown                        matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        DPlotTab                        matlab.ui.container.Tab
        PlottingOptionsDropDownLabel    matlab.ui.control.Label
        PlottingOptionsDropDown         matlab.ui.control.DropDown
        TimevsDistanceTab               matlab.ui.container.Tab
        UIAxesTime                      matlab.ui.control.UIAxes
        UndertheHoodTab                 matlab.ui.container.Tab
        UIAxesVelocity                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cla(app.UIAxesVelocity)
            cla(app.UIAxesTime)
            
            % Constant metabolic power
            if app.ConstantMetabolicPowerButton_2.Value
                % Common variables
                V_g = 0:0.01:20; % ground velocity for plot 1
                
                % Rider 1 variables
                MetP_R1 = app.MetabolicPowerWSpinner_2.Value;
                GE_R1 = app.GrossEfficiencySpinner_2.Value / 100;
                V_w_R1 = app.WindvelocitymsSpinner_2.Value; % wind velocity for rider 1
                p_R1 = app.Airdensitykgm3Spinner_2.Value; % air density for rider 1
                CdA_R1 = app.CdASpinner_2.Value; % CdA for rider 1
                D_w_R1 = app.WinddirectiondegreesSpinner_2.Value; % wind direction for rider 1
                D_b_R1 = app.RidedirectiondegreesSpinner_2.Value; % ride direction for rider 1
                F_w_R1 = app.SpokedragfactorSpinner_3.Value; % spoke drag for rider 1
                g_R1 = app.Gravityms2Spinner_2.Value; % gravity for rider 1
                M_r_R1 = app.RidermasskgSpinner_2.Value; % mass for rider 1
                M_b_R1 = app.BikemasskgSpinner_2.Value; % bike mass for rider 1
                a_R1 = app.GradientSpinner_2.Value; % gradient for rider 1
                mu_R1 = app.CrrSpinner.Value; % mu for rider 1
                
                % Rider 1 mechanical power output
                P_TOT_R1 = ...
                    totalCyclingPower(...
                    V_w_R1, V_g, p_R1, CdA_R1, D_w_R1, D_b_R1,...
                    F_w_R1, g_R1, M_r_R1, M_b_R1, a_R1, mu_R1);
                
                % Rider 2 variables
                MetP_R2 = app.MetabolicPowerWSpinner_3.Value;
                GE_R2 = app.GrossEfficiencySpinner_3.Value / 100;
                V_w_R2 = app.WindvelocitymsSpinner_3.Value; % wind velocity for rider 1
                p_R2 = app.Airdensitykgm3Spinner_3.Value; % air density for rider 1
                CdA_R2 = app.CdASpinner_3.Value; % CdA for rider 1
                D_w_R2 = app.WinddirectiondegreesSpinner_3.Value; % wind direction for rider 1
                D_b_R2 = app.RidedirectiondegreesSpinner_3.Value; % ride direction for rider 1
                F_w_R2 = app.SpokedragfactorSpinner_4.Value; % spoke drag for rider 1
                g_R2 = app.Gravityms2Spinner_3.Value; % gravity for rider 1
                M_r_R2 = app.RidermasskgSpinner_3.Value; % mass for rider 1
                M_b_R2 = app.BikemasskgSpinner_3.Value; % bike mass for rider 1
                a_R2 = app.GradientSpinner_3.Value; % gradient for rider 1
                mu_R2 = app.CoefficientoffrictionSpinner_4.Value; % mu for rider 1
                
                % Rider 2 mechanical power output
                P_TOT_R2 = ...
                    totalCyclingPower(...
                    V_w_R2, V_g, p_R2, CdA_R2, D_w_R2, D_b_R2,...
                    F_w_R2, g_R2, M_r_R2, M_b_R2, a_R2, mu_R2);                
                
                % Calculate time to travel 1km
                d = 0:1000;
                f_R1 = fit(P_TOT_R1',V_g','cubicinterp');
                MechP_R1 = app.MechanicalPowerWSpinner_2.Value;
                V_g_R1 = feval(f_R1,MechP_R1);
                t_R1 = d / V_g_R1;
                
                f_R2 = fit(P_TOT_R2',V_g','cubicinterp');
                MechP_R2 = app.MechanicalPowerWSpinner_3.Value;
                V_g_R2 = feval(f_R2,MechP_R2);
                t_R2 = d / V_g_R2;
                
                %Plot met. power vs. mech. power vs. velocity
                ax1 = app.UIAxesVelocity;
                plot3(ax1,V_g,P_TOT_R1,P_TOT_R1/GE_R1,...
                    V_g,P_TOT_R2,P_TOT_R2/GE_R2,'--')
                hold(ax1, 'on')
                scatter3(ax1, V_g_R1, MechP_R1, MetP_R1,50,'filled')
                scatter3(ax1, V_g_R2, MechP_R2, MetP_R2,50,'filled')
                
                axis(ax1,'square')
                xlim(ax1,[0 20])
                ylim(ax1,[0 1500])
                zlim(ax1,[0 6000])
                
%                 xticks(ax1,0:5:20)
%                 yticks(ax1,0:200:1400)
%                 zticks(ax1,0:1000:6000)
                
                xlabel(ax1,'Velocity (m/s)')
                ylabel(ax1,'Mechanical Power (W)')
                zlabel(ax1,'Metabolic Power (W)')
                
                grid(ax1,"on")
                legend(ax1,{'Rider 1','Rider 2'},...
                    'Location','northwest','Box','off')
                               
                %Plot time vs. distance
                ax2 = app.UIAxesTime;
                plot(ax2,d,t_R1,d,t_R2,'--')
                xlim(ax2,[0 1000])
                legend(ax2,{'Rider 1','Rider 2'},...
                    'Location','northwest','Box',"off")
                
                % Report results
                app.VelocitymsEditField.Value = V_g_R1;
                app.Timetotravel1kmsEditField.Value = t_R1(end);
                app.VelocitymsEditField_2.Value = V_g_R2;
                app.Timetotravel1kmsEditField_2.Value = t_R2(end);
                
                % Animate axes 1
                az = -40; el = 30; view(ax1,[az,el])
                degStep = 5; deltaT = 0.01;
                
                % MetP vs. Vel 
                % spin right
                for i = az:degStep:0; az = i; view(ax1,[az,el]); pause(deltaT); end
                % tilt up
                for i = el:-degStep:0; el = i; view(ax1,[az,el]); pause(deltaT); end
                pause(3)
                
                % Back to original
                % spin right to original
                for i = az:-degStep:-37; az = i; view(ax1,[az,el]); pause(deltaT); end
                
                % tilt up to original
                for i = el:degStep:30; el = i; view(ax1,[az,el]); pause(deltaT); end
                
                % MetP vs. MechP
                % spin left
                for i = az:-degStep:-90; az = i; view(ax1,[az,el]); pause(deltaT); end
                % tilt up
                for i = el:-degStep:0; el = i; view(ax1,[az,el]); pause(deltaT); end
                pause(3)
                
                % Back to original
                % spin right
                for i = az:degStep:-37; az = i; view(ax1,[az,el]); pause(deltaT); end
                
                % tilt up
                for i = el:degStep:30; el = i; view(ax1,[az,el]); pause(deltaT); end
                
                %% MechP vs. Vel 
                % spin right
                for i = az:degStep:0; az = i; view(ax1,[az,el]); pause(deltaT); end
                % tilt down
                for i = el:degStep:90; el = i; view(ax1,[az,el]); pause(deltaT); end
                pause(3)
                
                %% Back to original
                % spin right
                for i = az:-degStep:-37; az = i; view(ax1,[az,el]); pause(deltaT); end
                % tilt up
                for i = el:-degStep:30; el = i; view(ax1,[az,el]); pause(deltaT); end 
            end      
        end

        % Value changed function: GrossEfficiencySpinner_2
        function GrossEfficiencySpinner_2ValueChanged(app, event)
            GE = app.GrossEfficiencySpinner_2.Value / 100;
            MetP = app.MetabolicPowerWSpinner_2.Value;
            app.MechanicalPowerWSpinner_2.Value = MetP * GE;
        end

        % Value changed function: MetabolicPowerWSpinner_2
        function MetabolicPowerWSpinner_2ValueChanged(app, event)
            if app.ConstantMetabolicPowerButton_2.Value
                app.MetabolicPowerWSpinner_2.Enable = 'Off';
                app.MetabolicPowerWSpinner_2.Editable = 'Off';
            elseif app.ConstantGrossEfficiencyButton_2.Value
                MetP = app.MetabolicPowerWSpinner_2.Value;
                GE = app.GrossEfficiencySpinner_2.Value / 100;
                app.MechanicalPowerWSpinner_2.Value = MetP * GE;
            else


            end

        end

        % Selection changed function: ChooseascenarioButtonGroup_2
        function ChooseascenarioButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ChooseascenarioButtonGroup_2.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Rider2Label = uilabel(app.UIFigure);
            app.Rider2Label.Text = 'Rider 2';
            app.Rider1Label_2 = uilabel(app.UIFigure);
            app.Rider1Label_2.Text = 'Rider 1';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.PowerEfficiencyTab_2 = uitab(app.TabGroup_2);
            app.PowerEfficiencyTab_2.Title = 'Power & Efficiency';
            app.MetabolicPowerWSpinner_2Label = uilabel(app.PowerEfficiencyTab_2);
            app.MetabolicPowerWSpinner_2Label.Text = 'Metabolic Power (W)';
            app.MetabolicPowerWSpinner_2 = uispinner(app.PowerEfficiencyTab_2);
            app.MetabolicPowerWSpinner_2.Limits = [0 8000];
            app.MetabolicPowerWSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MetabolicPowerWSpinner_2ValueChanged, true);
            app.MetabolicPowerWSpinner_2.Value = 955;
            app.GrossEfficiencySpinner_2Label = uilabel(app.PowerEfficiencyTab_2);
            app.GrossEfficiencySpinner_2Label.Text = 'Gross Efficiency (%)';
            app.GrossEfficiencySpinner_2 = uispinner(app.PowerEfficiencyTab_2);
            app.GrossEfficiencySpinner_2.Step = 0.01;
            app.GrossEfficiencySpinner_2.Limits = [0 25];
            app.GrossEfficiencySpinner_2.ValueChangedFcn = createCallbackFcn(app, @GrossEfficiencySpinner_2ValueChanged, true);
            app.GrossEfficiencySpinner_2.Value = 22;
            app.MechanicalPowerWSpinner_2Label = uilabel(app.PowerEfficiencyTab_2);
            app.MechanicalPowerWSpinner_2Label.Text = 'Mechanical Power (W)';
            app.MechanicalPowerWSpinner_2 = uispinner(app.PowerEfficiencyTab_2);
            app.MechanicalPowerWSpinner_2.Limits = [0 2000];
            app.MechanicalPowerWSpinner_2.Value = 210;
            app.RiderEquipmentTab_2 = uitab(app.TabGroup_2);
            app.RiderEquipmentTab_2.Title = 'Rider & Equipment';
            app.RidermasskgSpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.RidermasskgSpinner_2Label.Text = 'Rider mass (kg)';
            app.RidermasskgSpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.RidermasskgSpinner_2.Step = 0.1;
            app.RidermasskgSpinner_2.Limits = [0 300];
            app.RidermasskgSpinner_2.Value = 80;
            app.BikemasskgSpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.BikemasskgSpinner_2Label.Text = 'Bike mass (kg)';
            app.BikemasskgSpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.BikemasskgSpinner_2.Step = 0.1;
            app.BikemasskgSpinner_2.Limits = [0 100];
            app.BikemasskgSpinner_2.Value = 10;
            app.CrrSpinnerLabel = uilabel(app.RiderEquipmentTab_2);
            app.CrrSpinnerLabel.Text = 'Crr';
            app.CrrSpinner = uispinner(app.RiderEquipmentTab_2);
            app.CrrSpinner.Step = 0.0001;
            app.CrrSpinner.Limits = [0 1];
            app.CrrSpinner.Value = 0.0032;
            app.SpokedragfactorSpinner_3Label = uilabel(app.RiderEquipmentTab_2);
            app.SpokedragfactorSpinner_3Label.Text = 'Spoke drag factor';
            app.SpokedragfactorSpinner_3 = uispinner(app.RiderEquipmentTab_2);
            app.SpokedragfactorSpinner_3.Step = 0.0001;
            app.SpokedragfactorSpinner_3.Limits = [0 1];
            app.SpokedragfactorSpinner_3.Value = 0.0044;
            app.CdASpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.CdASpinner_2Label.Text = 'CdA';
            app.CdASpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.CdASpinner_2.Step = 0.0005;
            app.CdASpinner_2.Limits = [0 1];
            app.CdASpinner_2.Value = 0.2565;
            app.EnvironmentTab_2 = uitab(app.TabGroup_2);
            app.EnvironmentTab_2.Title = 'Environment';
            app.Gravityms2Spinner_2Label = uilabel(app.EnvironmentTab_2);
            app.Gravityms2Spinner_2Label.Text = 'Gravity (m/s^2)';
            app.Gravityms2Spinner_2 = uispinner(app.EnvironmentTab_2);
            app.Gravityms2Spinner_2.Step = 0.01;
            app.Gravityms2Spinner_2.Limits = [-20 0];
            app.Gravityms2Spinner_2.Value = -9.81;
            app.GradientSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.GradientSpinner_2Label.Text = 'Gradient (%)';
            app.GradientSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.GradientSpinner_2.Step = 0.1;
            app.GradientSpinner_2.Limits = [-45 45];
            app.GradientSpinner_2.Value = 0.3;
            app.WinddirectiondegreesSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.WinddirectiondegreesSpinner_2Label.Text = 'Wind direction (degrees)';
            app.WinddirectiondegreesSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.WinddirectiondegreesSpinner_2.Limits = [0 360];
            app.WinddirectiondegreesSpinner_2.Value = 310;
            app.RidedirectiondegreesSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.RidedirectiondegreesSpinner_2Label.Text = 'Ride direction (degrees)';
            app.RidedirectiondegreesSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.RidedirectiondegreesSpinner_2.Limits = [0 360];
            app.RidedirectiondegreesSpinner_2.Value = 340;
            app.WindvelocitymsSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.WindvelocitymsSpinner_2Label.Text = 'Wind velocity (m/s)';
            app.WindvelocitymsSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.WindvelocitymsSpinner_2.Step = 0.01;
            app.WindvelocitymsSpinner_2.Limits = [0 100];
            app.WindvelocitymsSpinner_2.Value = 2.94;
            app.Airdensitykgm3Spinner_2Label = uilabel(app.EnvironmentTab_2);
            app.Airdensitykgm3Spinner_2Label.Text = 'Air density (kg/m^3)';
            app.Airdensitykgm3Spinner_2 = uispinner(app.EnvironmentTab_2);
            app.Airdensitykgm3Spinner_2.Step = 0.0001;
            app.Airdensitykgm3Spinner_2.Limits = [0 10];
            app.Airdensitykgm3Spinner_2.Value = 1.2234;
            app.ChooseascenarioButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ChooseascenarioButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ChooseascenarioButtonGroup_2SelectionChanged, true);
            app.ChooseascenarioButtonGroup_2.Title = 'Choose a scenario:';
            app.ConstantMetabolicPowerButton_2 = uiradiobutton(app.ChooseascenarioButtonGroup_2);
            app.ConstantMetabolicPowerButton_2.Text = 'Constant Metabolic Power';
            app.ConstantMetabolicPowerButton_2.Value = true;
            app.ConstantGrossEfficiencyButton_2 = uiradiobutton(app.ChooseascenarioButtonGroup_2);
            app.ConstantGrossEfficiencyButton_2.Text = 'Constant Gross Efficiency';
            app.ConstantMechanicalPowerButton_2 = uiradiobutton(app.ChooseascenarioButtonGroup_2);
            app.ConstantMechanicalPowerButton_2.Text = 'Constant Mechanical Power';
            app.TranslatingMetabolicEfficiencytoCyclingPerformanceLabel = uilabel(app.UIFigure);
            app.TranslatingMetabolicEfficiencytoCyclingPerformanceLabel.Text = 'Translating Metabolic Efficiency to Cycling Performance';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RestoredefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultButton.Text = 'Restore default';
            app.TabGroup_3 = uitabgroup(app.UIFigure);
            app.PowerEfficiencyTab_3 = uitab(app.TabGroup_3);
            app.PowerEfficiencyTab_3.Title = 'Power & Efficiency';
            app.MetabolicPowerWSpinner_3Label = uilabel(app.PowerEfficiencyTab_3);
            app.MetabolicPowerWSpinner_3Label.Text = 'Metabolic Power (W)';
            app.MetabolicPowerWSpinner_3 = uispinner(app.PowerEfficiencyTab_3);
            app.MetabolicPowerWSpinner_3.Limits = [0 8000];
            app.MetabolicPowerWSpinner_3.Value = 955;
            app.GrossEfficiencySpinner_3Label = uilabel(app.PowerEfficiencyTab_3);
            app.GrossEfficiencySpinner_3Label.Text = 'Gross Efficiency (%)';
            app.GrossEfficiencySpinner_3 = uispinner(app.PowerEfficiencyTab_3);
            app.GrossEfficiencySpinner_3.Step = 0.01;
            app.GrossEfficiencySpinner_3.Limits = [0 25];
            app.GrossEfficiencySpinner_3.Value = 22;
            app.MechanicalPowerWSpinner_3Label = uilabel(app.PowerEfficiencyTab_3);
            app.MechanicalPowerWSpinner_3Label.Text = 'Mechanical Power (W)';
            app.MechanicalPowerWSpinner_3 = uispinner(app.PowerEfficiencyTab_3);
            app.MechanicalPowerWSpinner_3.Limits = [0 2000];
            app.MechanicalPowerWSpinner_3.Value = 210;
            app.RiderEquipmentTab_3 = uitab(app.TabGroup_3);
            app.RiderEquipmentTab_3.Title = 'Rider & Equipment';
            app.RidermasskgSpinner_3Label = uilabel(app.RiderEquipmentTab_3);
            app.RidermasskgSpinner_3Label.Text = 'Rider mass (kg)';
            app.RidermasskgSpinner_3 = uispinner(app.RiderEquipmentTab_3);
            app.RidermasskgSpinner_3.Step = 0.1;
            app.RidermasskgSpinner_3.Limits = [0 300];
            app.RidermasskgSpinner_3.Value = 80;
            app.BikemasskgSpinner_3Label = uilabel(app.RiderEquipmentTab_3);
            app.BikemasskgSpinner_3Label.Text = 'Bike mass (kg)';
            app.BikemasskgSpinner_3 = uispinner(app.RiderEquipmentTab_3);
            app.BikemasskgSpinner_3.Step = 0.1;
            app.BikemasskgSpinner_3.Limits = [0 100];
            app.BikemasskgSpinner_3.Value = 10;
            app.CoefficientoffrictionSpinner_4Label = uilabel(app.RiderEquipmentTab_3);
            app.CoefficientoffrictionSpinner_4Label.Text = 'Coefficient of friction';
            app.CoefficientoffrictionSpinner_4 = uispinner(app.RiderEquipmentTab_3);
            app.CoefficientoffrictionSpinner_4.Step = 0.0001;
            app.CoefficientoffrictionSpinner_4.Limits = [0 1];
            app.CoefficientoffrictionSpinner_4.Value = 0.0032;
            app.SpokedragfactorSpinner_4Label = uilabel(app.RiderEquipmentTab_3);
            app.SpokedragfactorSpinner_4Label.Text = 'Spoke drag factor';
            app.SpokedragfactorSpinner_4 = uispinner(app.RiderEquipmentTab_3);
            app.SpokedragfactorSpinner_4.Step = 0.0001;
            app.SpokedragfactorSpinner_4.Limits = [0 1];
            app.SpokedragfactorSpinner_4.Value = 0.0044;
            app.CdASpinner_3Label = uilabel(app.RiderEquipmentTab_3);
            app.CdASpinner_3Label.Text = 'CdA';
            app.CdASpinner_3 = uispinner(app.RiderEquipmentTab_3);
            app.CdASpinner_3.Step = 0.0005;
            app.CdASpinner_3.Limits = [0 1];
            app.CdASpinner_3.Value = 0.2565;
            app.EnvironmentTab_3 = uitab(app.TabGroup_3);
            app.EnvironmentTab_3.Title = 'Environment';
            app.Gravityms2Spinner_3Label = uilabel(app.EnvironmentTab_3);
            app.Gravityms2Spinner_3Label.Text = 'Gravity (m/s^2)';
            app.Gravityms2Spinner_3 = uispinner(app.EnvironmentTab_3);
            app.Gravityms2Spinner_3.Step = 0.01;
            app.Gravityms2Spinner_3.Limits = [-20 0];
            app.Gravityms2Spinner_3.Value = -9.81;
            app.GradientSpinner_3Label = uilabel(app.EnvironmentTab_3);
            app.GradientSpinner_3Label.Text = 'Gradient (%)';
            app.GradientSpinner_3 = uispinner(app.EnvironmentTab_3);
            app.GradientSpinner_3.Step = 0.1;
            app.GradientSpinner_3.Limits = [-45 45];
            app.GradientSpinner_3.Value = 0.3;
            app.WinddirectiondegreesSpinner_3Label = uilabel(app.EnvironmentTab_3);
            app.WinddirectiondegreesSpinner_3Label.Text = 'Wind direction (degrees)';
            app.WinddirectiondegreesSpinner_3 = uispinner(app.EnvironmentTab_3);
            app.WinddirectiondegreesSpinner_3.Limits = [0 360];
            app.WinddirectiondegreesSpinner_3.Value = 310;
            app.RidedirectiondegreesSpinner_3Label = uilabel(app.EnvironmentTab_3);
            app.RidedirectiondegreesSpinner_3Label.Text = 'Ride direction (degrees)';
            app.RidedirectiondegreesSpinner_3 = uispinner(app.EnvironmentTab_3);
            app.RidedirectiondegreesSpinner_3.Limits = [0 360];
            app.RidedirectiondegreesSpinner_3.Value = 340;
            app.WindvelocitymsSpinner_3Label = uilabel(app.EnvironmentTab_3);
            app.WindvelocitymsSpinner_3Label.Text = 'Wind velocity (m/s)';
            app.WindvelocitymsSpinner_3 = uispinner(app.EnvironmentTab_3);
            app.WindvelocitymsSpinner_3.Step = 0.01;
            app.WindvelocitymsSpinner_3.Limits = [0 100];
            app.WindvelocitymsSpinner_3.Value = 2.94;
            app.Airdensitykgm3Spinner_3Label = uilabel(app.EnvironmentTab_3);
            app.Airdensitykgm3Spinner_3Label.Text = 'Air density (kg/m^3)';
            app.Airdensitykgm3Spinner_3 = uispinner(app.EnvironmentTab_3);
            app.Airdensitykgm3Spinner_3.Step = 0.0001;
            app.Airdensitykgm3Spinner_3.Limits = [0 10];
            app.Airdensitykgm3Spinner_3.Value = 1.2234;
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.VelocitymsEditFieldLabel = uilabel(app.ResultsPanel);
            app.VelocitymsEditFieldLabel.Text = 'Velocity (m/s)';
            app.VelocitymsEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.Timetotravel1kmsEditFieldLabel = uilabel(app.ResultsPanel);
            app.Timetotravel1kmsEditFieldLabel.Text = 'Time to travel 1km (s)';
            app.Timetotravel1kmsEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.VelocitymsEditField_2Label = uilabel(app.ResultsPanel);
            app.VelocitymsEditField_2Label.Text = 'Velocity (m/s)';
            app.VelocitymsEditField_2 = uieditfield(app.ResultsPanel, 'numeric');
            app.Timetotravel1kmsEditField_2Label = uilabel(app.ResultsPanel);
            app.Timetotravel1kmsEditField_2Label.Text = 'Time to travel 1km (s)';
            app.Timetotravel1kmsEditField_2 = uieditfield(app.ResultsPanel, 'numeric');
            app.Rider1Label_3 = uilabel(app.ResultsPanel);
            app.Rider1Label_3.Text = 'Rider 1';
            app.Rider2Label_2 = uilabel(app.ResultsPanel);
            app.Rider2Label_2.Text = 'Rider 2';
            app.UndertheHoodPanel = uipanel(app.UIFigure);
            app.UndertheHoodPanel.Title = 'Under the Hood';
            app.TextArea = uitextarea(app.UndertheHoodPanel);
            app.TextArea.Value = {'Select a variable or equation from the menu above to learn more.'};
            app.DropDown = uidropdown(app.UndertheHoodPanel);
            app.DropDown.Items = {'Metabolic Power', 'Gross Efficiency', 'Mechanical Power', 'Rider Mass', 'Bike Mass', 'CdA', 'Crr', 'Spoke drag factor', 'Gravity', 'Gradient', 'Wind Velocity', 'Wind Direction', 'Ride Direction', 'Air Density', 'Aerodynamic Power', 'Rolling Resistance Power', 'Wheel Bearing Friction Power', 'Potential Energy Power', 'Drivetrain Efficiency', 'Total Mechanical Power'};
            app.DropDown.Value = 'Metabolic Power';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3D Plot';
            app.PlottingOptionsDropDownLabel = uilabel(app.DPlotTab);
            app.PlottingOptionsDropDownLabel.Text = 'Plotting Options';
            app.PlottingOptionsDropDown = uidropdown(app.DPlotTab);
            app.PlottingOptionsDropDown.Items = {'3D', 'Metabolic Power vs. Velocity', 'Metabolic Power vs. Mechanical Power', 'Mechanical Power vs. Velocity'};
            app.PlottingOptionsDropDown.Value = '3D';
            app.TimevsDistanceTab = uitab(app.TabGroup);
            app.TimevsDistanceTab.Title = 'Time vs. Distance';
            app.UIAxesTime = uiaxes(app.TimevsDistanceTab);
            xlabel(app.UIAxesTime, 'Distance (m)')
            ylabel(app.UIAxesTime, 'Time (s)')
            zlabel(app.UIAxesTime, 'Z')
            app.UndertheHoodTab = uitab(app.TabGroup);
            app.UndertheHoodTab.Title = 'Under the Hood';
            app.UIAxesVelocity = uiaxes(app.UIFigure);
            xlabel(app.UIAxesVelocity, 'Velocity (m/s)')
            ylabel(app.UIAxesVelocity, 'Metabolic Power (W)')
            zlabel(app.UIAxesVelocity, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cyclingEfficiency

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end