classdef cyclingEfficiency_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TranslatingMetabolicEfficiencytoCyclingPerformanceLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Rider1Tab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        PowerEfficiencyTab              matlab.ui.container.Tab
        MetabolicPowerWSpinnerLabel     matlab.ui.control.Label
        MetabolicPowerWSpinner          matlab.ui.control.Spinner
        GrossEfficiencySpinnerLabel     matlab.ui.control.Label
        GrossEfficiencySpinner          matlab.ui.control.Spinner
        MechanicalPowerWSpinnerLabel    matlab.ui.control.Label
        MechanicalPowerWSpinner         matlab.ui.control.Spinner
        RiderEquipmentTab               matlab.ui.container.Tab
        RidermasskgSpinnerLabel         matlab.ui.control.Label
        RidermasskgSpinner              matlab.ui.control.Spinner
        BikemasskgSpinnerLabel          matlab.ui.control.Label
        BikemasskgSpinner               matlab.ui.control.Spinner
        CrrSpinnerLabel                 matlab.ui.control.Label
        CrrSpinner                      matlab.ui.control.Spinner
        SpokedragfactorSpinnerLabel     matlab.ui.control.Label
        SpokedragfactorSpinner          matlab.ui.control.Spinner
        CdASpinnerLabel                 matlab.ui.control.Label
        CdASpinner                      matlab.ui.control.Spinner
        EnvironmentTab                  matlab.ui.container.Tab
        Gravityms2SpinnerLabel          matlab.ui.control.Label
        Gravityms2Spinner               matlab.ui.control.Spinner
        GradientSpinnerLabel            matlab.ui.control.Label
        GradientSpinner                 matlab.ui.control.Spinner
        WinddirectiondegreesSpinnerLabel  matlab.ui.control.Label
        WinddirectiondegreesSpinner     matlab.ui.control.Spinner
        RidedirectiondegreesSpinnerLabel  matlab.ui.control.Label
        RidedirectiondegreesSpinner     matlab.ui.control.Spinner
        WindvelocitymsSpinnerLabel      matlab.ui.control.Label
        WindvelocitymsSpinner           matlab.ui.control.Spinner
        Airdensitykgm3SpinnerLabel      matlab.ui.control.Label
        Airdensitykgm3Spinner           matlab.ui.control.Spinner
        Rider2Tab                       matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        PowerEfficiencyTab_2            matlab.ui.container.Tab
        MetabolicPowerWSpinner_2Label   matlab.ui.control.Label
        MetabolicPowerWSpinner_2        matlab.ui.control.Spinner
        GrossEfficiencySpinner_2Label   matlab.ui.control.Label
        GrossEfficiencySpinner_2        matlab.ui.control.Spinner
        MechanicalPowerWSpinner_2Label  matlab.ui.control.Label
        MechanicalPowerWSpinner_2       matlab.ui.control.Spinner
        RiderEquipmentTab_2             matlab.ui.container.Tab
        RidermasskgSpinner_2Label       matlab.ui.control.Label
        RidermasskgSpinner_2            matlab.ui.control.Spinner
        BikemasskgSpinner_2Label        matlab.ui.control.Label
        BikemasskgSpinner_2             matlab.ui.control.Spinner
        CrrSpinner_2Label               matlab.ui.control.Label
        CrrSpinner_2                    matlab.ui.control.Spinner
        SpokedragfactorSpinner_2Label   matlab.ui.control.Label
        SpokedragfactorSpinner_2        matlab.ui.control.Spinner
        CdASpinner_2Label               matlab.ui.control.Label
        CdASpinner_2                    matlab.ui.control.Spinner
        EnvironmentTab_2                matlab.ui.container.Tab
        Gravityms2Spinner_2Label        matlab.ui.control.Label
        Gravityms2Spinner_2             matlab.ui.control.Spinner
        GradientSpinner_2Label          matlab.ui.control.Label
        GradientSpinner_2               matlab.ui.control.Spinner
        WinddirectiondegreesSpinner_2Label  matlab.ui.control.Label
        WinddirectiondegreesSpinner_2   matlab.ui.control.Spinner
        RidedirectiondegreesSpinner_2Label  matlab.ui.control.Label
        RidedirectiondegreesSpinner_2   matlab.ui.control.Spinner
        WindvelocitymsSpinner_2Label    matlab.ui.control.Label
        WindvelocitymsSpinner_2         matlab.ui.control.Spinner
        Airdensitykgm3Spinner_2Label    matlab.ui.control.Label
        Airdensitykgm3Spinner_2         matlab.ui.control.Spinner
        InputVariablesForEachRiderLabel  matlab.ui.control.Label
        CompareEffectofGrossEfficiencyonPanel  matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        RunComparisonButton             matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ResultsPanel                    matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        ViewOptionsDropDownLabel        matlab.ui.control.Label
        ViewOptionsDropDown             matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        DistancevsTimeTab               matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UndertheHoodTab                 matlab.ui.container.Tab
        DropDown                        matlab.ui.control.DropDown
        TextArea                        matlab.ui.control.TextArea
        Rider1Label                     matlab.ui.control.Label
        Rider2Label                     matlab.ui.control.Label
        kmTimesEditFieldLabel           matlab.ui.control.Label
        kmTimesEditField                matlab.ui.control.NumericEditField
        kmTimesEditField_2Label         matlab.ui.control.Label
        kmTimesEditField_2              matlab.ui.control.NumericEditField
        VelocitymsEditField_4Label      matlab.ui.control.Label
        VelocitymsEditField             matlab.ui.control.NumericEditField
        VelocitymsEditField_3Label      matlab.ui.control.Label
        VelocitymsEditField_2           matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Metabolic Power'
                    app.MetabolicPowerWSpinner.Enable = 'off';
                    app.MetabolicPowerWSpinner.Editable = 'off';
                    app.MetabolicPowerWSpinner_2.Enable = 'off';
                    app.MetabolicPowerWSpinner_2.Editable = 'off';
                    app.MechanicalPowerWSpinner.Enable = 'on';
                    app.MechanicalPowerWSpinner.Editable = 'on';
                    app.MechanicalPowerWSpinner_2.Enable = 'on';
                    app.MechanicalPowerWSpinner_2.Editable = 'on';
                case 'Mechanical Power'
                    app.MechanicalPowerWSpinner.Enable = 'off';
                    app.MechanicalPowerWSpinner.Editable = 'off';
                    app.MechanicalPowerWSpinner_2.Enable = 'off';
                    app.MechanicalPowerWSpinner_2.Editable = 'off';
                    app.MetabolicPowerWSpinner.Enable = 'on';
                    app.MetabolicPowerWSpinner.Editable = 'on';    
                    app.MetabolicPowerWSpinner_2.Enable = 'on';
                    app.MetabolicPowerWSpinner_2.Editable = 'on';
            end
        end

        % Value changed function: MetabolicPowerWSpinner
        function MetabolicPowerWSpinnerValueChanged(app, event)
            MetP = app.MetabolicPowerWSpinner.Value;
            GE = app.GrossEfficiencySpinner.Value / 100;
            app.MechanicalPowerWSpinner.Value = MetP * GE;
        end

        % Value changed function: MechanicalPowerWSpinner
        function MechanicalPowerWSpinnerValueChanged(app, event)
            MechP = app.MechanicalPowerWSpinner.Value;
            GE = app.GrossEfficiencySpinner.Value / 100;
            app.MetabolicPowerWSpinner.Value = MechP / GE;
        end

        % Value changed function: GrossEfficiencySpinner
        function GrossEfficiencySpinnerValueChanged(app, event)
            GE = app.GrossEfficiencySpinner.Value /100;
            switch app.Switch.Value
                case 'Metabolic Power'
                    MechP = app.MechanicalPowerWSpinner.Value;
                    app.MetabolicPowerWSpinner.Value = MechP / GE;
                case 'Mechanical Power'
                    MetP = app.MetabolicPowerWSpinner.Value;
                    app.MechanicalPowerWSpinner.Value = MetP * GE;
            end
        end

        % Value changed function: MechanicalPowerWSpinner_2
        function MechanicalPowerWSpinner_2ValueChanged(app, event)
            MechP = app.MechanicalPowerWSpinner_2.Value;
            GE = app.GrossEfficiencySpinner_2.Value / 100;
            app.MetabolicPowerWSpinner_2.Value = MechP / GE;        
        end

        % Value changed function: GrossEfficiencySpinner_2
        function GrossEfficiencySpinner_2ValueChanged(app, event)
            GE = app.GrossEfficiencySpinner_2.Value / 100;
            switch app.Switch.Value
                case 'Metabolic Power'
                    MechP = app.MechanicalPowerWSpinner_2.Value;
                    app.MetabolicPowerWSpinner_2.Value = MechP / GE;
                case 'Mechanical Power'
                    MetP = app.MetabolicPowerWSpinner_2.Value;
                    app.MechanicalPowerWSpinner_2.Value = MetP * GE;
            end
        end

        % Value changed function: MetabolicPowerWSpinner_2
        function MetabolicPowerWSpinner_2ValueChanged(app, event)
            MetP = app.MetabolicPowerWSpinner_2.Value;
            GE = app.GrossEfficiencySpinner_2.Value / 100;
            app.MechanicalPowerWSpinner_2.Value = MetP * GE;
        end

        % Button pushed function: RunComparisonButton
        function RunComparisonButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            
            % Common variables
            V_g = 0:0.01:20; % ground velocity for plot 1
            
            % Rider 1 variables
            MetP_R1 = app.MetabolicPowerWSpinner.Value;
            GE_R1 = app.GrossEfficiencySpinner.Value / 100;
            V_w_R1 = app.WindvelocitymsSpinner.Value; % wind velocity for rider 1
            p_R1 = app.Airdensitykgm3Spinner.Value; % air density for rider 1
            CdA_R1 = app.CdASpinner.Value; % CdA for rider 1
            D_w_R1 = app.WinddirectiondegreesSpinner.Value; % wind direction for rider 1
            D_b_R1 = app.RidedirectiondegreesSpinner.Value; % ride direction for rider 1
            F_w_R1 = app.SpokedragfactorSpinner.Value; % spoke drag for rider 1
            g_R1 = app.Gravityms2Spinner.Value; % gravity for rider 1
            M_r_R1 = app.RidermasskgSpinner.Value; % mass for rider 1
            M_b_R1 = app.BikemasskgSpinner.Value; % bike mass for rider 1
            a_R1 = app.GradientSpinner.Value; % gradient for rider 1
            mu_R1 = app.CrrSpinner.Value; % mu for rider 1
            
            % Rider 1 mechanical power output
            P_TOT_R1 = ...
                totalCyclingPower(...
                V_w_R1, V_g, p_R1, CdA_R1, D_w_R1, D_b_R1,...
                F_w_R1, g_R1, M_r_R1, M_b_R1, a_R1, mu_R1);
            
            % Rider 2 variables
            MetP_R2 = app.MetabolicPowerWSpinner_2.Value;
            GE_R2 = app.GrossEfficiencySpinner_2.Value / 100;
            V_w_R2 = app.WindvelocitymsSpinner_2.Value; % wind velocity for rider 1
            p_R2 = app.Airdensitykgm3Spinner_2.Value; % air density for rider 1
            CdA_R2 = app.CdASpinner_2.Value; % CdA for rider 1
            D_w_R2 = app.WinddirectiondegreesSpinner_2.Value; % wind direction for rider 1
            D_b_R2 = app.RidedirectiondegreesSpinner_2.Value; % ride direction for rider 1
            F_w_R2 = app.SpokedragfactorSpinner_2.Value; % spoke drag for rider 1
            g_R2 = app.Gravityms2Spinner_2.Value; % gravity for rider 1
            M_r_R2 = app.RidermasskgSpinner_2.Value; % mass for rider 1
            M_b_R2 = app.BikemasskgSpinner_2.Value; % bike mass for rider 1
            a_R2 = app.GradientSpinner_2.Value; % gradient for rider 1
            mu_R2 = app.CrrSpinner_2.Value; % mu for rider 1
            
            % Rider 2 mechanical power output
            P_TOT_R2 = ...
                totalCyclingPower(...
                V_w_R2, V_g, p_R2, CdA_R2, D_w_R2, D_b_R2,...
                F_w_R2, g_R2, M_r_R2, M_b_R2, a_R2, mu_R2);                
            
            % Calculate time to travel 1km
            d = 0:1000;
            f_R1 = fit(P_TOT_R1',V_g','cubicinterp');
            MechP_R1 = app.MechanicalPowerWSpinner.Value;
            V_g_R1 = feval(f_R1,MechP_R1);
            t_R1 = d / V_g_R1;
            
            f_R2 = fit(P_TOT_R2',V_g','cubicinterp');
            MechP_R2 = app.MechanicalPowerWSpinner_2.Value;
            V_g_R2 = feval(f_R2,MechP_R2);
            t_R2 = d / V_g_R2;
            
            %Plot met. power vs. mech. power vs. velocity
            ax1 = app.UIAxes;
            plot3(ax1,P_TOT_R1/GE_R1,P_TOT_R1,V_g,...
                P_TOT_R2/GE_R2,P_TOT_R2,V_g,'--')
            hold(ax1, 'on')
            scatter3(ax1, MetP_R1, MechP_R1, V_g_R1,50,'filled')
            scatter3(ax1, MetP_R2, MechP_R2, V_g_R2,50,'filled')
            
            axis(ax1,'square')
            xlim(ax1,[0 4000])
            ylim(ax1,[0 1000])
            zlim(ax1,[0 20])
            
            xlabel(ax1,{'Metabolic','Power (W)'})
            ylabel(ax1,{'Mechanical','Power (W)'})
            zlabel(ax1,'Velocity (m/s)')
            
            box(ax1,'on')
            grid(ax1,'on')
            title(ax1,'')
            legend(ax1,{'Rider 1','Rider 2'},...
                'Location','northwest','Box','off')
                           
            %Plot distance vs. time
            ax2 = app.UIAxes2;
            plot(ax2,t_R1,d/1000,t_R2,d/1000,'--')
            ylim(ax2,[0 1])
            title(ax2,'')
            legend(ax2,{'Rider 1','Rider 2'},...
                'Location','northwest','Box',"off")
            
            % Report results
            app.VelocitymsEditField.Value = V_g_R1;
            app.kmTimesEditField.Value = t_R1(end);
            app.VelocitymsEditField_2.Value = V_g_R2;
            app.kmTimesEditField_2.Value = t_R2(end);            
        end

        % Value changed function: ViewOptionsDropDown
        function ViewOptionsDropDownValueChanged(app, event)
            value = app.ViewOptionsDropDown.Value;
            list = app.ViewOptionsDropDown.Items;
            ax1 = app.UIAxes;
            switch value
                case list{1}
                    view(ax1,-37.5,30)
                case list{2}
                    view(ax1,0,0)
                case list{3}
                    view(ax1,0,90)
                case list{4}
                    view(ax1,90,0)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TranslatingMetabolicEfficiencytoCyclingPerformanceLabel = uilabel(app.LeftPanel);
            app.TranslatingMetabolicEfficiencytoCyclingPerformanceLabel.Text = 'Translating Metabolic Efficiency to Cycling Performance';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Rider1Tab = uitab(app.TabGroup);
            app.Rider1Tab.Title = 'Rider 1';
            app.TabGroup2 = uitabgroup(app.Rider1Tab);
            app.PowerEfficiencyTab = uitab(app.TabGroup2);
            app.PowerEfficiencyTab.Title = 'Power & Efficiency';
            app.MetabolicPowerWSpinnerLabel = uilabel(app.PowerEfficiencyTab);
            app.MetabolicPowerWSpinnerLabel.Enable = 'off';
            app.MetabolicPowerWSpinnerLabel.Text = 'Metabolic Power (W)';
            app.MetabolicPowerWSpinner = uispinner(app.PowerEfficiencyTab);
            app.MetabolicPowerWSpinner.Limits = [0 8000];
            app.MetabolicPowerWSpinner.ValueChangedFcn = createCallbackFcn(app, @MetabolicPowerWSpinnerValueChanged, true);
            app.MetabolicPowerWSpinner.Editable = 'off';
            app.MetabolicPowerWSpinner.Enable = 'off';
            app.MetabolicPowerWSpinner.Value = 1000;
            app.GrossEfficiencySpinnerLabel = uilabel(app.PowerEfficiencyTab);
            app.GrossEfficiencySpinnerLabel.Text = 'Gross Efficiency (%)';
            app.GrossEfficiencySpinner = uispinner(app.PowerEfficiencyTab);
            app.GrossEfficiencySpinner.Step = 0.01;
            app.GrossEfficiencySpinner.Limits = [0 25];
            app.GrossEfficiencySpinner.ValueChangedFcn = createCallbackFcn(app, @GrossEfficiencySpinnerValueChanged, true);
            app.GrossEfficiencySpinner.Value = 22;
            app.MechanicalPowerWSpinnerLabel = uilabel(app.PowerEfficiencyTab);
            app.MechanicalPowerWSpinnerLabel.Text = 'Mechanical Power (W)';
            app.MechanicalPowerWSpinner = uispinner(app.PowerEfficiencyTab);
            app.MechanicalPowerWSpinner.Limits = [0 2000];
            app.MechanicalPowerWSpinner.ValueChangedFcn = createCallbackFcn(app, @MechanicalPowerWSpinnerValueChanged, true);
            app.MechanicalPowerWSpinner.Value = 220;
            app.RiderEquipmentTab = uitab(app.TabGroup2);
            app.RiderEquipmentTab.Title = 'Rider & Equipment';
            app.RidermasskgSpinnerLabel = uilabel(app.RiderEquipmentTab);
            app.RidermasskgSpinnerLabel.Text = 'Rider mass (kg)';
            app.RidermasskgSpinner = uispinner(app.RiderEquipmentTab);
            app.RidermasskgSpinner.Step = 0.1;
            app.RidermasskgSpinner.Limits = [0 300];
            app.RidermasskgSpinner.Value = 80;
            app.BikemasskgSpinnerLabel = uilabel(app.RiderEquipmentTab);
            app.BikemasskgSpinnerLabel.Text = 'Bike mass (kg)';
            app.BikemasskgSpinner = uispinner(app.RiderEquipmentTab);
            app.BikemasskgSpinner.Step = 0.1;
            app.BikemasskgSpinner.Limits = [0 100];
            app.BikemasskgSpinner.Value = 10;
            app.CrrSpinnerLabel = uilabel(app.RiderEquipmentTab);
            app.CrrSpinnerLabel.Text = 'Crr';
            app.CrrSpinner = uispinner(app.RiderEquipmentTab);
            app.CrrSpinner.Step = 0.0001;
            app.CrrSpinner.Limits = [0 1];
            app.CrrSpinner.Value = 0.0032;
            app.SpokedragfactorSpinnerLabel = uilabel(app.RiderEquipmentTab);
            app.SpokedragfactorSpinnerLabel.Text = 'Spoke drag factor';
            app.SpokedragfactorSpinner = uispinner(app.RiderEquipmentTab);
            app.SpokedragfactorSpinner.Step = 0.0001;
            app.SpokedragfactorSpinner.Limits = [0 1];
            app.SpokedragfactorSpinner.Value = 0.0044;
            app.CdASpinnerLabel = uilabel(app.RiderEquipmentTab);
            app.CdASpinnerLabel.Text = 'CdA';
            app.CdASpinner = uispinner(app.RiderEquipmentTab);
            app.CdASpinner.Step = 0.0005;
            app.CdASpinner.Limits = [0 1];
            app.CdASpinner.Value = 0.2565;
            app.EnvironmentTab = uitab(app.TabGroup2);
            app.EnvironmentTab.Title = 'Environment';
            app.Gravityms2SpinnerLabel = uilabel(app.EnvironmentTab);
            app.Gravityms2SpinnerLabel.Text = 'Gravity (m/s^2)';
            app.Gravityms2Spinner = uispinner(app.EnvironmentTab);
            app.Gravityms2Spinner.Step = 0.01;
            app.Gravityms2Spinner.Limits = [-20 0];
            app.Gravityms2Spinner.Value = -9.81;
            app.GradientSpinnerLabel = uilabel(app.EnvironmentTab);
            app.GradientSpinnerLabel.Text = 'Gradient (%)';
            app.GradientSpinner = uispinner(app.EnvironmentTab);
            app.GradientSpinner.Step = 0.1;
            app.GradientSpinner.Limits = [-45 45];
            app.GradientSpinner.Value = 5;
            app.WinddirectiondegreesSpinnerLabel = uilabel(app.EnvironmentTab);
            app.WinddirectiondegreesSpinnerLabel.Text = 'Wind direction (degrees)';
            app.WinddirectiondegreesSpinner = uispinner(app.EnvironmentTab);
            app.WinddirectiondegreesSpinner.Limits = [0 360];
            app.RidedirectiondegreesSpinnerLabel = uilabel(app.EnvironmentTab);
            app.RidedirectiondegreesSpinnerLabel.Text = 'Ride direction (degrees)';
            app.RidedirectiondegreesSpinner = uispinner(app.EnvironmentTab);
            app.RidedirectiondegreesSpinner.Limits = [0 360];
            app.WindvelocitymsSpinnerLabel = uilabel(app.EnvironmentTab);
            app.WindvelocitymsSpinnerLabel.Text = 'Wind velocity (m/s)';
            app.WindvelocitymsSpinner = uispinner(app.EnvironmentTab);
            app.WindvelocitymsSpinner.Step = 0.01;
            app.WindvelocitymsSpinner.Limits = [0 100];
            app.Airdensitykgm3SpinnerLabel = uilabel(app.EnvironmentTab);
            app.Airdensitykgm3SpinnerLabel.Text = 'Air density (kg/m^3)';
            app.Airdensitykgm3Spinner = uispinner(app.EnvironmentTab);
            app.Airdensitykgm3Spinner.Step = 0.0001;
            app.Airdensitykgm3Spinner.Limits = [0 10];
            app.Airdensitykgm3Spinner.Value = 1.2234;
            app.Rider2Tab = uitab(app.TabGroup);
            app.Rider2Tab.Title = 'Rider 2';
            app.TabGroup2_2 = uitabgroup(app.Rider2Tab);
            app.PowerEfficiencyTab_2 = uitab(app.TabGroup2_2);
            app.PowerEfficiencyTab_2.Title = 'Power & Efficiency';
            app.MetabolicPowerWSpinner_2Label = uilabel(app.PowerEfficiencyTab_2);
            app.MetabolicPowerWSpinner_2Label.Enable = 'off';
            app.MetabolicPowerWSpinner_2Label.Text = 'Metabolic Power (W)';
            app.MetabolicPowerWSpinner_2 = uispinner(app.PowerEfficiencyTab_2);
            app.MetabolicPowerWSpinner_2.Limits = [0 8000];
            app.MetabolicPowerWSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MetabolicPowerWSpinner_2ValueChanged, true);
            app.MetabolicPowerWSpinner_2.Editable = 'off';
            app.MetabolicPowerWSpinner_2.Enable = 'off';
            app.MetabolicPowerWSpinner_2.Value = 1000;
            app.GrossEfficiencySpinner_2Label = uilabel(app.PowerEfficiencyTab_2);
            app.GrossEfficiencySpinner_2Label.Text = 'Gross Efficiency (%)';
            app.GrossEfficiencySpinner_2 = uispinner(app.PowerEfficiencyTab_2);
            app.GrossEfficiencySpinner_2.Step = 0.01;
            app.GrossEfficiencySpinner_2.Limits = [0 25];
            app.GrossEfficiencySpinner_2.ValueChangedFcn = createCallbackFcn(app, @GrossEfficiencySpinner_2ValueChanged, true);
            app.GrossEfficiencySpinner_2.Value = 21;
            app.MechanicalPowerWSpinner_2Label = uilabel(app.PowerEfficiencyTab_2);
            app.MechanicalPowerWSpinner_2Label.Text = 'Mechanical Power (W)';
            app.MechanicalPowerWSpinner_2 = uispinner(app.PowerEfficiencyTab_2);
            app.MechanicalPowerWSpinner_2.Limits = [0 2000];
            app.MechanicalPowerWSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MechanicalPowerWSpinner_2ValueChanged, true);
            app.MechanicalPowerWSpinner_2.Value = 210;
            app.RiderEquipmentTab_2 = uitab(app.TabGroup2_2);
            app.RiderEquipmentTab_2.Title = 'Rider & Equipment';
            app.RidermasskgSpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.RidermasskgSpinner_2Label.Text = 'Rider mass (kg)';
            app.RidermasskgSpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.RidermasskgSpinner_2.Step = 0.1;
            app.RidermasskgSpinner_2.Limits = [0 300];
            app.RidermasskgSpinner_2.Value = 80;
            app.BikemasskgSpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.BikemasskgSpinner_2Label.Text = 'Bike mass (kg)';
            app.BikemasskgSpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.BikemasskgSpinner_2.Step = 0.1;
            app.BikemasskgSpinner_2.Limits = [0 100];
            app.BikemasskgSpinner_2.Value = 10;
            app.CrrSpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.CrrSpinner_2Label.Text = 'Crr';
            app.CrrSpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.CrrSpinner_2.Step = 0.0001;
            app.CrrSpinner_2.Limits = [0 1];
            app.CrrSpinner_2.Value = 0.0032;
            app.SpokedragfactorSpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.SpokedragfactorSpinner_2Label.Text = 'Spoke drag factor';
            app.SpokedragfactorSpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.SpokedragfactorSpinner_2.Step = 0.0001;
            app.SpokedragfactorSpinner_2.Limits = [0 1];
            app.SpokedragfactorSpinner_2.Value = 0.0044;
            app.CdASpinner_2Label = uilabel(app.RiderEquipmentTab_2);
            app.CdASpinner_2Label.Text = 'CdA';
            app.CdASpinner_2 = uispinner(app.RiderEquipmentTab_2);
            app.CdASpinner_2.Step = 0.0005;
            app.CdASpinner_2.Limits = [0 1];
            app.CdASpinner_2.Value = 0.2565;
            app.EnvironmentTab_2 = uitab(app.TabGroup2_2);
            app.EnvironmentTab_2.Title = 'Environment';
            app.Gravityms2Spinner_2Label = uilabel(app.EnvironmentTab_2);
            app.Gravityms2Spinner_2Label.Text = 'Gravity (m/s^2)';
            app.Gravityms2Spinner_2 = uispinner(app.EnvironmentTab_2);
            app.Gravityms2Spinner_2.Step = 0.01;
            app.Gravityms2Spinner_2.Limits = [-20 0];
            app.Gravityms2Spinner_2.Value = -9.81;
            app.GradientSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.GradientSpinner_2Label.Text = 'Gradient (%)';
            app.GradientSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.GradientSpinner_2.Step = 0.1;
            app.GradientSpinner_2.Limits = [-45 45];
            app.GradientSpinner_2.Value = 5;
            app.WinddirectiondegreesSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.WinddirectiondegreesSpinner_2Label.Text = 'Wind direction (degrees)';
            app.WinddirectiondegreesSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.WinddirectiondegreesSpinner_2.Limits = [0 360];
            app.RidedirectiondegreesSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.RidedirectiondegreesSpinner_2Label.Text = 'Ride direction (degrees)';
            app.RidedirectiondegreesSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.RidedirectiondegreesSpinner_2.Limits = [0 360];
            app.WindvelocitymsSpinner_2Label = uilabel(app.EnvironmentTab_2);
            app.WindvelocitymsSpinner_2Label.Text = 'Wind velocity (m/s)';
            app.WindvelocitymsSpinner_2 = uispinner(app.EnvironmentTab_2);
            app.WindvelocitymsSpinner_2.Step = 0.01;
            app.WindvelocitymsSpinner_2.Limits = [0 100];
            app.Airdensitykgm3Spinner_2Label = uilabel(app.EnvironmentTab_2);
            app.Airdensitykgm3Spinner_2Label.Text = 'Air density (kg/m^3)';
            app.Airdensitykgm3Spinner_2 = uispinner(app.EnvironmentTab_2);
            app.Airdensitykgm3Spinner_2.Step = 0.0001;
            app.Airdensitykgm3Spinner_2.Limits = [0 10];
            app.Airdensitykgm3Spinner_2.Value = 1.2234;
            app.InputVariablesForEachRiderLabel = uilabel(app.LeftPanel);
            app.InputVariablesForEachRiderLabel.Text = 'Input Variables For Each Rider';
            app.CompareEffectofGrossEfficiencyonPanel = uipanel(app.LeftPanel);
            app.CompareEffectofGrossEfficiencyonPanel.Title = 'Compare Effect of Gross Efficiency on:';
            app.Switch = uiswitch(app.CompareEffectofGrossEfficiencyonPanel, 'slider');
            app.Switch.Items = {'Metabolic Power', 'Mechanical Power'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Metabolic Power';
            app.RunComparisonButton = uibutton(app.LeftPanel, 'push');
            app.RunComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @RunComparisonButtonPushed, true);
            app.RunComparisonButton.Text = 'Run Comparison';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultsPanel = uipanel(app.RightPanel);
            app.ResultsPanel.Title = 'Results';
            app.TabGroup3 = uitabgroup(app.ResultsPanel);
            app.PlotTab = uitab(app.TabGroup3);
            app.PlotTab.Title = 'Plot';
            app.ViewOptionsDropDownLabel = uilabel(app.PlotTab);
            app.ViewOptionsDropDownLabel.Text = 'View Options';
            app.ViewOptionsDropDown = uidropdown(app.PlotTab);
            app.ViewOptionsDropDown.Items = {'Original', 'Velocity vs. Metabolic Power', 'Mechanical Power vs. Metabolic Power', 'Velocity vs. Mechanical Power'};
            app.ViewOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewOptionsDropDownValueChanged, true);
            app.ViewOptionsDropDown.Value = 'Original';
            app.UIAxes = uiaxes(app.PlotTab);
            xlabel(app.UIAxes, 'Metabolic Power (W)')
            ylabel(app.UIAxes, 'Mechanical Power (W)')
            zlabel(app.UIAxes, 'Velocity (m/s)')
            app.DistancevsTimeTab = uitab(app.TabGroup3);
            app.DistancevsTimeTab.Title = 'Distance vs. Time';
            app.UIAxes2 = uiaxes(app.DistancevsTimeTab);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Distance (km)')
            zlabel(app.UIAxes2, 'Z')
            app.UndertheHoodTab = uitab(app.TabGroup3);
            app.UndertheHoodTab.Title = 'Under the Hood';
            app.DropDown = uidropdown(app.UndertheHoodTab);
            app.DropDown.Items = {'', 'Metabolic Power', 'Gross Efficiency', 'Mechanical Power', 'Rider Mass', 'Bike Mass', 'CdA', 'Crr', 'Spoke Drag Factor', 'Gravity', 'Gradient', 'Wind Velocity', 'Wind Direction', 'Ride Direction', 'Air Density', 'Aerodynamic Power', 'Rolling Resistance Power', 'Wheel Bearing Friction Power', 'Potential Energy', 'Drivetrain Efficiency', 'Total Mechanical Power'};
            app.DropDown.Value = '';
            app.TextArea = uitextarea(app.UndertheHoodTab);
            app.TextArea.Value = {'Select an option from the drop down menu to learn more.'};
            app.Rider1Label = uilabel(app.ResultsPanel);
            app.Rider1Label.Text = 'Rider 1';
            app.Rider2Label = uilabel(app.ResultsPanel);
            app.Rider2Label.Text = 'Rider 2';
            app.kmTimesEditFieldLabel = uilabel(app.ResultsPanel);
            app.kmTimesEditFieldLabel.Text = '1km Time (s)';
            app.kmTimesEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.kmTimesEditField_2Label = uilabel(app.ResultsPanel);
            app.kmTimesEditField_2Label.Text = '1km Time (s)';
            app.kmTimesEditField_2 = uieditfield(app.ResultsPanel, 'numeric');
            app.VelocitymsEditField_4Label = uilabel(app.ResultsPanel);
            app.VelocitymsEditField_4Label.Text = 'Velocity (m/s)';
            app.VelocitymsEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.VelocitymsEditField_3Label = uilabel(app.ResultsPanel);
            app.VelocitymsEditField_3Label.Text = 'Velocity(m/s)';
            app.VelocitymsEditField_2 = uieditfield(app.ResultsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cyclingEfficiency_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end